classdef Geodash < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainMenuTab                    matlab.ui.container.Tab
        StartButton                    matlab.ui.control.Button
        CharcterColorColorPicker       matlab.ui.control.ColorPicker
        CharcterColorColorPickerLabel  matlab.ui.control.Label
        CharacterEdgeColorColorPicker  matlab.ui.control.ColorPicker
        CharacterEdgeColorColorPickerLabel  matlab.ui.control.Label
        TitleImage                     matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        ENGRDashTab                    matlab.ui.container.Tab
        PauseButton                    matlab.ui.control.Button
        JumpButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        CommonIssueSolutionsTab        matlab.ui.container.Tab
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
    end

    
    properties (Access = public)
        Property % Universal variables that can be used across function and callbacks
            music

            player_length
            player_width
            player_Xposition
            player_Yposition
            player_Xvelocity
            player_Yvelocity 
            Player_orientation
            jump_Yvelocity
            jump_Xvelocity
            gravity

            inductor1_img_path
            inductor1_img
            inductor2_img_path
            inductor2_img
            disp_inductor1
            disp_inductor2

            capacitor1_img_path
            capacitor2_img_path
            capacitor1_img
            capacitor2_img
            disp_capacitor1
            disp_capacitor2

            resistor1_img_path
            resistor2_img_path
            resistor1_img
            resistor2_img
            disp_resistor1
            disp_resistor2

            obstacle_xi1
            obstacle_xi2
            obstacle_xi3
            obstacle_xi4
            obstacle_xi5
            obstacle_xi6
            obstacle_yi1
            obstacle_yi2
            obs_choose
            

            isPaused logical = false;
    end
    
    % ----------- where the timers go -------------
    properties (Access = private)
           titleTimer         
           titleImageBaseY = 277;

           jumpTimer
           jumpTime = 0

           obstacleSpawnTimer

           % array holding active timers to keep track of them 
           activeObstacleTimers = {}
    end
    
    methods (Access = private)
        
        % stops the timer and deletes it but first checks if it is not
        % already empty and exists
        function stopAppTimers(app)
            if ~isempty(app.titleTimer) && isvalid(app.titleTimer)
                stop(app.titleTimer);
                delete(app.titleTimer);
            end
        end
        
        % function called when closing game
        function onClose(app, ~)
            stopAppTimers(app);  % Call the cleanup funciton
            delete(app);         % Close the app
        end
        
        % function that animates the title screen
        function animateTitleImage(app)
          
          % this saves the value of t between funciton calls (kinda acts
          % like a public variable but only for this funciton)
          persistent t

          % initiallizes t if it is empty
          if isempty(t)
             t = 0;
          end
          
          % the increment everytime this function is called by the timer
          t = t + 0.032;

         % Bouncing motion 10 pixels t per frame 
          bounce = 10 * sin(t); 

          % current position
          oldPos = app.TitleImage.Position;

          % new y position
          newY = app.titleImageBaseY + bounce;
        
        % Apply new position to title image
          app.TitleImage.Position = [oldPos(1), newY, oldPos(3), oldPos(4)];
          end
        
        function updateJumpFrame(app)
            app.jumpTime = app.jumpTime + 0.1;
            t = app.jumpTime;

            y_jump = (app.jump_Yvelocity * t) + (1/2 .* (app.gravity) * t^2); % y-motion equation
            x_jump =  ((app.jump_Xvelocity * t) + 10); % x-motion equation

            app.Player_orientation.Position = [x_jump, y_jump, app.player_length, app.player_width];
            
            % check pause functionality
            while app.isPaused
                
                pause(0.1);
            
            end

            if y_jump <= 0
                stop(app.jumpTimer);
                delete(app.jumpTimer);
            
                app.player_Yposition = 0;  % Reset internal Y
                
                app.JumpButton.Enable = 'on';
                focus(app.UIFigure);
                return;
            end
        end
        % ------------------- spawn obstacle ---------------------------
        function spawnObstacle(app)

                % check for pause
                if app.isPaused
                    return;
                end
            
                speed = 1;  %NEED TO TWEAK
            
                % changed the if / if else to switches
                switch randi(6)
                    case 1  % Resistor 1
                        h = image(app.UIAxes, ...
                            'XData', [115, 125], ...
                            'YData', [0.58, 40], ...
                            'CData', app.resistor1_img);
            
                    case 2  % Resistor 2
                        h = image(app.UIAxes, ...
                            'XData', [115, 152.5], ...
                            'YData', [-5.5, 5.5], ...
                            'CData', app.resistor2_img);
            
                    case 3  % Inductor 1
                        h = image(app.UIAxes, ...
                            'XData', [115, 125], ...
                            'YData', [0.58, 40], ...
                            'CData', app.inductor1_img);
            
                    case 4  % Inductor 2
                        h = image(app.UIAxes, ...
                            'XData', [115, 152.5], ...
                            'YData', [-5.5, 5.5], ...
                            'CData', app.inductor2_img);
            
                    case 5  % Capacitor 1
                        h = image(app.UIAxes, ...
                            'XData', [115, 125], ...
                            'YData', [-15, 15], ...
                            'CData', app.capacitor1_img);
            
                    case 6  % Capacitor 2
                        h = image(app.UIAxes, ...
                            'XData', [115, 125], ...
                            'YData', [0.58, 40], ...
                            'CData', app.capacitor2_img);
                end
            
                % creates timer object
                t = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.016, ...
                    'TimerFcn', @(~,~) moveObstacle(app, h, speed));
                
                % starts the timer and saves it to the list of active
                % timers
                start(t); 
                app.activeObstacleTimers{end+1} = struct('timer', t, 'image', h);
        end

        % ------------------------- move obstacle and delete timer and
        % obstacle if its off screen -----------------------------------
        function moveObstacle(app, h, speed)

            % check for app pause, and pause object if it still exists
             if app.isPaused || ~isvalid(h) || isempty(h)
                    return;
             end
            
                % move the obstacle to the left
                h.XData = h.XData - speed;
            
                % delete object and its timer when object goes off screen
                if h.XData(2) < 0

                        % delete image
                        delete(h);  

                
                        % delete timer corresponding to image
                        % loop through list of timers
                        for i = 1:numel(app.activeObstacleTimers)

                            % new name for convenience
                            entry = app.activeObstacleTimers{i};

                            % search through for an image match and delete
                            % its timer
                            if isequal(entry.image, h)
                                t = entry.timer;
                                if isvalid(t)
                                    stop(t);
                                    delete(t);
                                end

                                % remove the object/timer from active list
                                app.activeObstacleTimers(i) = []; 
                                break; % break when it found the match (no need to check the rest)
                            end
                        end
                 end        
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %store original y position of title image
            app.titleImageBaseY = app.TitleImage.Position(2);
            
            % continuously calls the animateTitleImage every 0.016s (60fps) 
            app.titleTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.016, ...
                'TimerFcn', @(~,~) animateTitleImage(app));
            start(app.titleTimer);
            
            %stops when closing game, 
            app.UIFigure.CloseRequestFcn = @(src, event) onClose(app);

            % Obstacles
            %Inductors
            app.inductor1_img_path = fullfile(pwd,'Inductorvert.png'); % Vertical Inductor image
            app.inductor2_img_path = fullfile(pwd,'Inductorhorz.png'); % Horizontal Inductor image
            %Capacitors
            app.capacitor1_img_path = fullfile(pwd,'Capacitorvert.png'); % Vertical Capacitor image
            app.capacitor2_img_path = fullfile(pwd,'Capacitorhorz.png'); % Horizontal Capacitor image
            %Resistors
            app.resistor1_img_path = fullfile(pwd,'Resistorvert.png'); % Vertical Resistor image
            app.resistor2_img_path = fullfile(pwd,'Resistorhorz.png'); % Horizontal Resistor image


            % Inductor reads
            app.inductor1_img = imread(app.inductor1_img_path);
            app.inductor2_img = imread(app.inductor2_img_path);
            %Capacitor reads
            app.capacitor1_img = imread(app.capacitor1_img_path);
            app.capacitor2_img = imread(app.capacitor2_img_path);
            %Resistor reads
            app.resistor1_img = imread(app.resistor1_img_path);
            app.resistor2_img = imread(app.resistor2_img_path);

            % Plots Obstacles
            % Plotted to keep proportions and position relative to the x-axis
            % consistent to what the game will be like.
            %Inductor Plots
            %app.disp_inductor1 = image(app.UIAxes, 'XData', [115 , 125], 'YData', [0.58 , 40], 'CData' , app.inductor1_img);
            %app.disp_inductor2 = image(app.UIAxes, 'XData', [115 , 152.5], 'YData', [-5.5 , 5.5], 'CData' , app.inductor2_img);
            %Capacitor Plots
            %app.disp_capacitor1 = image(app.UIAxes, 'XData', [115 , 125], 'YData', [-15 , 15], 'CData' , app.capacitor1_img);
            %app.disp_capacitor2 = image(app.UIAxes, 'XData', [115 , 152.5], 'YData', [0.58 , 40], 'CData' , app.capacitor2_img);
            % Resistor Plots
            %app.disp_resistor1 = image(app.UIAxes, 'XData', [115 , 125], 'YData', [0.58 , 40], 'CData' , app.resistor1_img);
            %app.disp_resistor2 = image(app.UIAxes, 'XData', [115 , 152.5], 'YData', [-5.5 , 5.5], 'CData' , app.resistor2_img);
            
 
            % Resistor Plots
            %app.disp_resistor1 = image(app.UIAxes, 'XData', [app.obstacle_xi1 - dX , app.obstacle_xi2 - dX], 'YData', [0.58 , 40], 'CData' , app.resistor1_img);
            %app.disp_resistor2 = image(app.UIAxes, 'XData', [app.obstacle_xi3 - dX , app.obstacle_xi4 - dX], 'YData', [-5.5 , 5.5], 'CData' , app.resistor2_img);
            %Inductor Plots
            %app.disp_inductor1 = image(app.UIAxes, 'XData', [app.obstacle_xi1 - dX , app.obstacle_xi2 - dX], 'YData', [0.58 , 40], 'CData' , app.inductor1_img);
            %app.disp_inductor2 = image(app.UIAxes, 'XData', [app.obstacle_xi3 - dX , app.obstacle_xi4 - dX], 'YData', [-5.5 , 5.5], 'CData' , app.inductor2_img);
            %Capacitor Plots
            %app.disp_capacitor1 = image(app.UIAxes, 'XData', [app.obstacle_xi5 - dX , app.obstacle_xi6 - dX], 'YData', [-15 , 15], 'CData' , app.capacitor1_img);
            %app.disp_capacitor2 = image(app.UIAxes, 'XData', [app.obstacle_xi1 - dX , app.obstacle_xi2 - dX], 'YData', [0.58 , 40], 'CData' , app.capacitor2_img);

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            app.TabGroup.SelectedTab = app.ENGRDashTab; % When clickling the start button it switches to the game tab

            %Starts music
            app.music = true; % Sets intial state of music till the game ends then it turns off music by returning false(still needs to be made)
            [y,Fs] = audioread('Short.mp3'); % Gets sample rate and sample frequency from audio file
            sound(y,Fs) % Plays sound using the sample rate and frequency
            clear y Fs;
            
            % Initializes plot area
            hold(app.UIAxes, 'on'); % Allows you to draw multiple shapes without deleting previous ones
            app.UIAxes.XColor = 'none'; % Sets x-axis color
            app.UIAxes.YColor = 'none'; % Sets y-axis color
            app.UIAxes.Color = 'none'; % Sets figure color(background color)
            app.UIAxes.XLabel.String = ''; % Hide x-axis label
            app.UIAxes.YLabel.String = ''; % Hides y-axis label
            x_values = linspace(0, 115, 116); % Sets x-value range of plot
            y_values = linspace(-15, 100, 116); % Sets y-value range of plot
            plot(app.UIAxes , x_values , y_values , 'Color', 'none') % Plots a invisible line to set the x and y ranges of the plot
            hold(app.UIAxes)
            plot(app.UIAxes , x_values , linspace(0 , 0 , 116) , 'Color', 'k', 'LineWidth', 5)
            xlim(app.UIAxes , [0 115]); % Sustains x-value range
            ylim(app.UIAxes, [-15 100]); % Sustains y-value range

            %Sets Player properties
            app.player_length = 5; % m
            app.player_width = 5; % m
            app.player_Xposition = 10; % m
            app.player_Yposition = 0; % m
            app.player_Xvelocity = 0; % m/s
            app.player_Yvelocity = 0; % m/s

            % Draws player on plot
            app.Player_orientation = rectangle(app.UIAxes, 'Position', [app.player_Xposition app.player_Yposition app.player_length app.player_width], 'FaceColor', app.CharcterColorColorPicker.Value , 'EdgeColor', app.CharacterEdgeColorColorPicker.Value , 'LineWidth', 2);

            % bring focus to game so keyboard input works
            focus(app.UIFigure);

            % Start obstacle spawning timer (spawns every 4 seconds - for now TWEAK)
            app.obstacleSpawnTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 4, ...
                'TimerFcn', @(~,~) spawnObstacle(app));
            start(app.obstacleSpawnTimer);

%{

            % Obstacle movement test
            app.obstacle_xi1 = 115;
            app.obstacle_xi2 = 125;
            app.obstacle_xi3 = 115;
            app.obstacle_xi4 = 152.5;
            app.obstacle_xi5 = 115;
            app.obstacle_xi6 = 125;
            app.obstacle_yi1 = 0.58;
            app.obstacle_yi2 = 40;


            while true  % Infinite loop to keep the obstacles coming


            % Resistor Plots
            app.disp_resistor1 = image(app.UIAxes, 'XData', [115 , 125], 'YData', [0.58 , 40], 'CData' , app.resistor1_img);
            app.disp_resistor2 = image(app.UIAxes, 'XData', [115 , 152.5], 'YData', [-5.5 , 5.5], 'CData' , app.resistor2_img);
            %Inductor Plots
            app.disp_inductor1 = image(app.UIAxes, 'XData', [115 , 125], 'YData', [0.58 , 40], 'CData' , app.inductor1_img);
            app.disp_inductor2 = image(app.UIAxes, 'XData', [115 , 152.5], 'YData', [-5.5 , 5.5], 'CData' , app.inductor2_img);
            %Capacitor Plots
            app.disp_capacitor1 = image(app.UIAxes, 'XData', [115 , 125], 'YData', [-15 , 15], 'CData' , app.capacitor1_img);
            app.disp_capacitor2 = image(app.UIAxes, 'XData', [115 , 125], 'YData', [0.58 , 40], 'CData' , app.capacitor2_img);


                    app.obs_choose = randi(6);
    
                    if app.obs_choose == 1
                        for dX = 0 : 1 : 125
                            
                            % check pause functionality
                            while app.isPaused
                                
                                pause(0.1);
                            end

                            app.disp_resistor1.XData = [app.obstacle_xi1 - dX, app.obstacle_xi2 - dX];
                            pause(0.035)
                        end
                            if dX == 125
                                delete(app.disp_resistor1)
                            end
        
                    elseif app.obs_choose == 2
                        for dX = 0 : 1 : 153

                            % check pause functionality
                            while app.isPaused
                                
                                pause(0.1);
                            end

                            app.disp_resistor2.XData = [app.obstacle_xi3 - dX, app.obstacle_xi4 - dX];
                            pause(0.035)
                        end
                            if dX == 153
                                delete(app.disp_resistor2)
                            end
        
                    elseif app.obs_choose == 3
                        for dX = 0 : 1 : 125

                            % check pause functionality
                            while app.isPaused
                                
                                pause(0.1);
                            end

                            app.disp_inductor1.XData = [app.obstacle_xi1 - dX, app.obstacle_xi2 - dX];
                            pause(0.035)
                        end
                            if dX == 125
                                delete(app.disp_inductor1)
                            end

                    elseif app.obs_choose == 4
                        for dX = 0 : 1 : 153

                            % check pause functionality
                            while app.isPaused
                                
                                pause(0.1);
                            end

                            app.disp_inductor2.XData = [app.obstacle_xi3 - dX, app.obstacle_xi4 - dX];
                            pause(0.035)
                        end
                            if dX == 153
                                delete(app.disp_inductor2)
                            end
        
                    elseif app.obs_choose == 5
                        for dX = 0 : 1 : 125

                            % check pause functionality
                            while app.isPaused
                                
                                pause(0.1);
                            end

                            app.disp_capacitor1.XData = [app.obstacle_xi5 - dX, app.obstacle_xi6 - dX];
                            pause(0.035)
                        end
                            if dX == 125
                                delete(app.disp_capacitor1)
                            end
        
                    elseif app.obs_choose == 6
                        for dX = 0 : 1 : 125

                            % check pause functionality
                            while app.isPaused
                                
                                pause(0.1);
                            end

                            app.disp_capacitor2.XData = [app.obstacle_xi1 - dX, app.obstacle_xi2 - dX];
                            pause(0.035)
                        end
                            if dX == 125
                                delete(app.disp_capacitor2)
                            end
                   end
            end
%}

            %for dX = 0 : 1 : 115

            %app.disp_resistor1.XData = [app.obstacle_xi1 - dX , app.obstacle_xi2 - dX];
            %app.disp_resistor2.XData = [app.obstacle_xi3 - dX , app.obstacle_xi4 - dX];

            %app.disp_capacitor1.XData = [app.obstacle_xi5 - dX , app.obstacle_xi6 - dX];
            %app.disp_capacitor2.XData = [app.obstacle_xi1 - dX , app.obstacle_xi2 - dX];

            %app.disp_inductor1.XData = [app.obstacle_xi1 - dX , app.obstacle_xi2 - dX];
            %app.disp_inductor2.XData = [app.obstacle_xi3 - dX , app.obstacle_xi4 - dX];

            %pause(0.035)
            

            %end

            %if dX == 115

            %delete(app.disp_resistor1)
            %delete(app.disp_resistor2)
            %delete(app.disp_capacitor1)
            %delete(app.disp_capacitor2)
            %delete(app.disp_inductor1)
            %delete(app.disp_inductor2)

            %end

            
        end

        % Button pushed function: JumpButton
        function JumpButtonPushed(app, event)

            % Sets more player properties and environment properties
            app.jump_Yvelocity = 17.5;
            app.jump_Xvelocity = 0;
            app.gravity = -3;
            app.jumpTime = 0;
        
            app.JumpButton.Enable = 'off';
        
            app.jumpTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.016, ...
                'TimerFcn', @(~,~) updateJumpFrame(app));
        
            start(app.jumpTimer);
            
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            % flips pause/unpause
            app.isPaused = ~app.isPaused;  
    
             if app.isPaused
                 app.PauseButton.Text = 'Resume';
                 app.JumpButton.Enable = 'off';
             else
                 app.PauseButton.Text = 'Pause';
                 app.JumpButton.Enable = 'on';
             end
            
             % brings focus back to game after pausing/unpausing so
             % keyboard input still works
             focus(app.UIFigure);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
           
            key = event.Key;
            % can only jump with spacebase if space is pressed, game is not
            % paused, jump button is enabled
            if (strcmp(key, 'space') && ~app.isPaused && strcmp(app.JumpButton.Enable, 'on'))

               JumpButtonPushed(app, event);  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainMenuTab = uitab(app.TabGroup);
            app.MainMenuTab.Title = 'Main Menu';
            app.Image = uiimage(app.MainMenuTab);
            app.Image.ImageSource = 'background.png';
            app.TitleImage = uiimage(app.MainMenuTab);
            app.TitleImage.ImageSource = 'engineering dash title.png';
            app.CharacterEdgeColorColorPickerLabel = uilabel(app.MainMenuTab);
            app.CharacterEdgeColorColorPickerLabel.Text = 'Character Edge Color';
            app.CharacterEdgeColorColorPicker = uicolorpicker(app.MainMenuTab);
            app.CharcterColorColorPickerLabel = uilabel(app.MainMenuTab);
            app.CharcterColorColorPickerLabel.Text = 'Charcter Color';
            app.CharcterColorColorPicker = uicolorpicker(app.MainMenuTab);
            app.StartButton = uibutton(app.MainMenuTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ENGRDashTab = uitab(app.TabGroup);
            app.ENGRDashTab.Title = 'ENGR Dash';
            app.UIAxes = uiaxes(app.ENGRDashTab);
            xlabel(app.UIAxes, 'x-axis(m)')
            ylabel(app.UIAxes, 'y-axis(m)')
            app.JumpButton = uibutton(app.ENGRDashTab, 'push');
            app.JumpButton.ButtonPushedFcn = createCallbackFcn(app, @JumpButtonPushed, true);
            app.JumpButton.Text = 'Jump';
            app.PauseButton = uibutton(app.ENGRDashTab, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.CommonIssueSolutionsTab = uitab(app.TabGroup);
            app.CommonIssueSolutionsTab.Title = 'Common Issue Solutions';
            app.Label = uilabel(app.CommonIssueSolutionsTab);
            app.Label.Text = 'If it says internal device error completely close out of MATLAB and  then open it back up again';
            app.Label_2 = uilabel(app.CommonIssueSolutionsTab);
            app.Label_2.Text = 'If there are issues with the music running other than the one mentiod previously make sure the music file has exact same name in your working folder as it is in the code. If not change one to match the other.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Geodash

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end